classdef audiogram_KP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        uipanel1                     matlab.ui.container.Panel
        uipanel3                     matlab.ui.container.Panel
        VolumeSlider                 matlab.ui.control.Slider
        FrequencySlider              matlab.ui.control.Slider
        uibuttongroup3               matlab.ui.container.ButtonGroup
        radiobutton1                 matlab.ui.control.RadioButton
        radiobutton2                 matlab.ui.control.RadioButton
        FrequencyHzLabel             matlab.ui.control.Label
        VolumedBLabel                matlab.ui.control.Label
        TESTbutton                   matlab.ui.control.Button
        uipanel2                     matlab.ui.container.Panel
        CALIBRATIONButton            matlab.ui.control.Button
        YESButton                    matlab.ui.control.Button
        DoyouhearonlyonesoundLabel   matlab.ui.control.Label
        LoudSlider                   matlab.ui.control.Slider
        SetyourvolumebeforecalibrationLabel  matlab.ui.control.Label
        EXITButton                   matlab.ui.control.Button
        RESULTSButton                matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        HearinglossforleftearLabel   matlab.ui.control.Label
        HearinglossforrightearLabel  matlab.ui.control.Label
        AveragehearinglossforbothearsLabel  matlab.ui.control.Label
        LabelLeft                    matlab.ui.control.Label
        LabelRight                   matlab.ui.control.Label
        LabelAll                     matlab.ui.control.Label
        LabelAll_2                   matlab.ui.control.Label
        LabelAll_3                   matlab.ui.control.Label
        LabelAll_4                   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

        
    properties (Access = public)
        channel = 0;
        K = 0;
        A = 0;
        f = 125;
        Ty_right = [0,0,0,0,0,0,0,0,0];
        Ty_left = [0,0,0,0,0,0,0,0,0];
        Tx = [125,500,1000,2000,3000,4000,6000,8000,10000];
        answer=questdlg('Set volume of your device at max level.',...
                'INFO','OK','OK');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function untitled1_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to audiogram_KP (see VARARGIN)
            
            % Choose default command line output for audiogram_KP
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
            app.f = value;
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
            app.A = value;
        end

        % Button pushed function: CALIBRATIONButton
        function CALIBRATIONButtonPushed(app, event)
            %kalibracja
            clear sound;
            fs=48000;
            f=1000;
            t=0:1/fs:1;
            %A=1;
            y=app.K/100*sin(2*pi*f*t);
            Py=10*log10(sum(y.^2));
            coef=10.^(5/20);
            yy=y.*coef;
            Pyy=10*log10(sum(yy.^2));
            zz=[];
            for i=1:10
                z1=y(1,1:48000);
                z2=yy(1,1:48000);
                zz=[zz z1 z2];
            end
            sound(zz,fs);
        end

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            answer=questdlg('Do you want exit?',...
                'Exit','Yes','No','No');
            switch answer
                case 'Yes'
                    closereq();
                case 'No'
                    return
            end
        end

        % Selection changed function: uibuttongroup3
        function uibuttongroup3SelectionChanged(app, event)
            selectedButton = app.uibuttongroup3.SelectedObject;
            if(selectedButton==app.radiobutton1)
                app.channel=0;
            elseif(selectedButton==app.radiobutton2)
                app.channel=1;
            end
        end

        % Button pushed function: TESTbutton
        function TESTbuttonButtonPushed(app, event)
        fs=48000;
        t=0:1/fs:1;

        tt=[];
        for i=1:5
        z1=t(1,1:48000);
        tt=[tt z1];
        end
            
            P=(app.K/10000)*10^(app.A/10);
            y=P*sin(2*pi*app.f*tt);
            outL = [y; zeros(size(tt))];
            outR = [zeros(size(tt)); y];
            if(app.channel == 0)
                sound(outL,fs);
            elseif(app.channel ==1)
                sound(outR,fs);
            end
            hear_answer = questdlg('Do you hear?',...
                'TEST','Yes','No','No');
            
            switch hear_answer
                case 'Yes'
                    clear sound;
                    if (app.f ~= 10000)
                        yes_answer = questdlg('Level up frequency and set volume at 0dB.',...
                        'Instruction','OK','OK');
                    else
                        end_answer = questdlg('End of test. Thank you!',...
                        'Thank you','OK','OK');
                    end
                    
                    if (app.channel == 0)
                        switch app.f
                            case 125
                                app.Ty_left(1) = app.A;
                            case 500
                                app.Ty_left(2) = app.A;
                            case 1000
                                app.Ty_left(3) = app.A;
                            case 2000
                                app.Ty_left(4) = app.A;
                            case 3000
                                app.Ty_left(5) = app.A;
                            case 4000
                                app.Ty_left(6) = app.A;
                            case 6000
                                app.Ty_left(7) = app.A;
                            case 8000
                                app.Ty_left(8) = app.A;
                            case 10000
                                app.Ty_left(9) = app.A;
                        end
                    elseif (app.channel == 1)
                        switch app.f
                            case 125
                                app.Ty_right(1) = app.A;
                            case 500
                                app.Ty_right(2) = app.A;
                            case 1000
                                app.Ty_right(3) = app.A;
                            case 2000
                                app.Ty_right(4) = app.A;
                            case 3000
                                app.Ty_right(5) = app.A;
                            case 4000
                                app.Ty_right(6) = app.A;
                            case 6000
                                app.Ty_right(7) = app.A;
                            case 8000
                                app.Ty_right(8) = app.A;
                            case 10000
                                app.Ty_right(9) = app.A;
                        end
                    end
                plot(app.Tx,app.Ty_left,'g-o',app.Tx,app.Ty_right,'r-o');
                legend('Left ear','Right ear');
                case 'No'
                    clear sound;
                    if (app.K ~= 0)
                        if (app.A ~= 130)
                            no_answer = questdlg('Level up volume.',...
                        'Instruction','OK','OK');
                        else
                            no_answer_1 = questdlg('There is not upper level. Try again.',...
                        'Instruction','OK','OK');
                        end
                    else
                        no_answer_2 = questdlg('Your loud is at 0.',...
                        'Help','OK','OK');
                    end
            end
        end

        % Value changed function: LoudSlider
        function LoudSliderValueChanged(app, event)
            app.K = app.LoudSlider.Value;
            
        end

        % Button pushed function: RESULTSButton
        function RESULTSButtonPushed(app, event)
            if((app.Ty_left(6) - app.Ty_left(3)) > 40)
                LeftResults = (app.Ty_left(3) + app.Ty_left(4) + app.Ty_left(6) + app.Ty_left(8))/4;
            elseif((app.Ty_left(6) - app.Ty_left(3)) > 40 && app.Ty_left(8) > app.Ty_left(6))
                LeftResults = (app.Ty_left(3) + app.Ty_left(4) + app.Ty_left(8))/3;
            else
                LeftResults = (app.Ty_left(3) + app.Ty_left(4) + app.Ty_left(6))/3;
            end
            app.LabelLeft.Text = sprintf('%0.1f', LeftResults);


            if((app.Ty_right(6) - app.Ty_right(3)) > 40)
                RightResults = (app.Ty_right(3) + app.Ty_right(4) + app.Ty_right(6) + app.Ty_right(8))/4;
                
            elseif((app.Ty_right(6) - app.Ty_right(3)) > 40 && app.Ty_right(8) > app.Ty_right(6))
                RightResults = (app.Ty_right(3) + app.Ty_right(4) + app.Ty_right(8))/3;
            else
                RightResults = (app.Ty_right(3) + app.Ty_right(4) + app.Ty_right(6))/3;
            end
            app.LabelRight.Text = sprintf('%0.1f', RightResults);


            if(((RightResults - LeftResults) > 25) || ((LeftResults - RightResults) > 25) )
                if(LeftResults > RightResults)
                    AllResults = RightResults + 5;
                else
                    AllResults = LeftResults + 5;
                end
            else
                if(LeftResults > RightResults)
                    AllResults = RightResults;
                else
                    AllResults = LeftResults;
                end
            end
            app.LabelAll.Text = sprintf('%0.1f', AllResults);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'untitled1';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Audiogram';
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Title = 'HEARING TEST';
            app.uipanel3.Tag = 'uipanel3';
            app.VolumeSlider = uislider(app.uipanel3);
            app.VolumeSlider.Limits = [0 130];
            app.VolumeSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.MinorTicks = [];
            app.FrequencySlider = uislider(app.uipanel3);
            app.FrequencySlider.Limits = [125 10000];
            app.FrequencySlider.MajorTicks = [125 500 1000 2000 3000 4000 6000 8000 10000];
            app.FrequencySlider.MajorTickLabels = {'125', '500', '1000', '2000', '3000', '4000', '6000', '8000', '10000'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.MinorTicks = [];
            app.FrequencySlider.Value = 125;
            app.uibuttongroup3 = uibuttongroup(app.uipanel3);
            app.uibuttongroup3.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup3SelectionChanged, true);
            app.uibuttongroup3.Title = 'CHOOSE SIDE OF YOUR HEAD, PLZ';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.radiobutton1 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = 'Left side';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.uibuttongroup3);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = 'Right side';
            app.FrequencyHzLabel = uilabel(app.uipanel3);
            app.FrequencyHzLabel.Text = 'Frequency [Hz]';
            app.VolumedBLabel = uilabel(app.uipanel3);
            app.VolumedBLabel.Text = 'Volume [dB]';
            app.TESTbutton = uibutton(app.uipanel3, 'push');
            app.TESTbutton.ButtonPushedFcn = createCallbackFcn(app, @TESTbuttonButtonPushed, true);
            app.TESTbutton.Tag = 'pushbutton2';
            app.TESTbutton.Text = 'TEST';
            app.uipanel2 = uipanel(app.uipanel3);
            app.uipanel2.Title = 'CALIBRATION';
            app.uipanel2.Tag = 'uipanel2';
            app.CALIBRATIONButton = uibutton(app.uipanel2, 'push');
            app.CALIBRATIONButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATIONButtonPushed, true);
            app.CALIBRATIONButton.Text = 'CALIBRATION';
            app.YESButton = uibutton(app.uipanel2, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Text = 'YES';
            app.DoyouhearonlyonesoundLabel = uilabel(app.uipanel2);
            app.DoyouhearonlyonesoundLabel.Text = 'Do you hear only one sound?';
            app.LoudSlider = uislider(app.uipanel2);
            app.LoudSlider.ValueChangedFcn = createCallbackFcn(app, @LoudSliderValueChanged, true);
            app.SetyourvolumebeforecalibrationLabel = uilabel(app.uipanel2);
            app.SetyourvolumebeforecalibrationLabel.Text = 'Set your volume before calibration';
            app.EXITButton = uibutton(app.uipanel3, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.RESULTSButton = uibutton(app.uipanel3, 'push');
            app.RESULTSButton.ButtonPushedFcn = createCallbackFcn(app, @RESULTSButtonPushed, true);
            app.RESULTSButton.Text = 'RESULTS';
            app.Image = uiimage(app.uipanel1);
            app.Image.ImageSource = 'Scale.png';
            app.HearinglossforleftearLabel = uilabel(app.uipanel1);
            app.HearinglossforleftearLabel.Text = 'Hearing loss for left ear:';
            app.HearinglossforrightearLabel = uilabel(app.uipanel1);
            app.HearinglossforrightearLabel.Text = 'Hearing loss for right ear:';
            app.AveragehearinglossforbothearsLabel = uilabel(app.uipanel1);
            app.AveragehearinglossforbothearsLabel.Text = 'Average hearing loss for both ears:';
            app.LabelLeft = uilabel(app.uipanel1);
            app.LabelLeft.Text = '0';
            app.LabelRight = uilabel(app.uipanel1);
            app.LabelRight.Text = '0';
            app.LabelAll = uilabel(app.uipanel1);
            app.LabelAll.Text = '0';
            app.LabelAll_2 = uilabel(app.uipanel1);
            app.LabelAll_2.Text = 'dB';
            app.LabelAll_3 = uilabel(app.uipanel1);
            app.LabelAll_3.Text = 'dB';
            app.LabelAll_4 = uilabel(app.uipanel1);
            app.LabelAll_4.Text = 'dB';
            app.UIAxes = uiaxes(app.uipanel1);
            title(app.UIAxes, 'Audiogram')
            xlabel(app.UIAxes, 'Frequency [Hz]')
            ylabel(app.UIAxes, 'Volume [dB]')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiogram_KP(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)untitled1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end